classdef application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        TempssimEditField            matlab.ui.control.NumericEditField
        TempssimEditFieldLabel       matlab.ui.control.Label
        TabGroup                     matlab.ui.container.TabGroup
        Tab                          matlab.ui.container.Tab
        DnominateurEditField_2       matlab.ui.control.EditField
        DnominateurEditField_2Label  matlab.ui.control.Label
        NumrateurEditField_2         matlab.ui.control.EditField
        NumrateurEditField_2Label    matlab.ui.control.Label
        Tab_2                        matlab.ui.container.Tab
        DnominateurEditField         matlab.ui.control.EditField
        DnominateurEditFieldLabel    matlab.ui.control.Label
        NumrateurEditField           matlab.ui.control.EditField
        NumrateurEditFieldLabel      matlab.ui.control.Label
        Tab_3                        matlab.ui.container.Tab
        DnominateurEditField_3       matlab.ui.control.EditField
        DnominateurEditField_3Label  matlab.ui.control.Label
        NumrateurEditField_3         matlab.ui.control.EditField
        NumrateurEditField_3Label    matlab.ui.control.Label
        ThermistancesLabel           matlab.ui.control.Label
        RgulateurLabel               matlab.ui.control.Label
        Image                        matlab.ui.control.Image
        ValideretlancerButton        matlab.ui.control.Button
        IEditField                   matlab.ui.control.NumericEditField
        IEditFieldLabel              matlab.ui.control.Label
        PEditField                   matlab.ui.control.NumericEditField
        PEditFieldLabel              matlab.ui.control.Label
        ConsigneEditField            matlab.ui.control.NumericEditField
        ConsigneEditFieldLabel       matlab.ui.control.Label
        UIAxes                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        Timer % Description
    end
    
    methods (Access = public)
        function startSimulation(app, tempsSimulation)
            f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
            %f.CloseRequestFcn = @(src,event)my_closereq(src);

            %function my_closereq(fig)
               % delete(f);
              %  f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');
             %   return
            %end

            set_param('Design2simulink', 'SimulationCommand', 'start');
            pause(1);
            while get_param('Design2simulink', 'SimulationTime') < tempsSimulation && get_param('Design2simulink', 'SimulationTime') > 1
                if ishandle(f)
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, f, 'Simulation en cours');
                else
                    f = waitbar(get_param('Design2simulink', 'SimulationTime')/tempsSimulation, 'Simulation en cours');                    
                end
                pause(1);
            end
           
            % Close dialog box%get_param('Design2simulink', 'SimulationTime');
            %get_param('Design2simulink',  '')
            signal_consigne = evalin('base', 'out.simout1');%simOut.get('signal_commande');
            %signal_consigne = get_param('Design2simulink/Scope'
            signal_sortie = evalin('base', 'out.simout');
            signal_t2 = evalin('base', 'out.simout2');
            signal_t3 = evalin('base', 'out.simout3');
            %signal_sortie = signal_sortie.signals.values;
            %signal_consigne = evalin('base', 'ScopeData');
            %signal_sortie = evalin('base', 'ScopeData');
            %disp(evalin('base', 'out').ScopeData(1));
            N = length(signal_sortie);
            temps = linspace(0, tempsSimulation, N);
            % Get the scope data from MATLAB workspace and plot it
            plot(app.UIAxes, temps, signal_consigne, 'b', 'LineWidth', 1.5);
            hold(app.UIAxes, 'on');

            plot(app.UIAxes, temps, signal_sortie, 'r--', 'Linewidth', 1.5);
            plot(app.UIAxes, temps, signal_t2, 'g-.', 'LineWidth', 1.5);
            plot(app.UIAxes, temps, signal_t3, 'k:', 'LineWidth', 1.5)
            hold(app.UIAxes, 'off');


            legend(app.UIAxes, {'Consigne', 'T3', 'T1', 'T2'}, 'Location', 'best');
            
            title(app.UIAxes, 'Réponse du système');
            xlabel(app.UIAxes, 'Temps (s)');
            ylabel(app.UIAxes, 'Température');
            grid(app.UIAxes, 'on');
            close(f);
            
            
            % Créer une structure avec les paramètres
            %params = struct( ...
            %'P', P, ...
            %'I', I, ...
            %'Consigne', Consigne, ...
            %'TF1', struct('Numerator', num1, 'Denominator', den1), ...
            %'TF2', struct('Numerator', num2, 'Denominator', den2), ...
            %'TF3', struct('Numerator', num3, 'Denominator', den3), ...
            %'Horodatage', datetime("now", 'yyyy-mm-dd_HH-MM-SS') ...
            %);

            % Convertir en JSON
            %jsonStr = jsonencode(params);

            % Enregistrer dans un fichier
            %filename = ['parametres_simulation_' datetime("now", 'yyyymmdd_HHMMSS') '.json'];
            %fid = fopen(filename, 'w');
            %if fid ~= -1
             %   fwrite(fid, jsonStr, 'char');
              %  fclose(fid);
               % disp(['Paramètres sauvegardés dans : ' filename]);
            %else
             %   uialert(app.UIFigure, 'Erreur lors de l''écriture du fichier JSON', 'Erreur');
            %end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

        end

        % Button down function: UIAxes, UIFigure
        function UIFigureButtonDown(app, event)
        
        %end

        %function ValideretlancerButtonPushed(app, event)
    
         %   end


        end

        % Button pushed function: ValideretlancerButton
        function ValideretlancerButtonPushed(app, event)

            % Lire les valeurs
                P = app.PEditField.Value;
                I = app.IEditField.Value;
                Consigne = app.ConsigneEditField.Value;
                tempsSimulation = app.TempssimEditField.Value;

    % Lire les TF
                num1 = eval(app.NumrateurEditField_2.Value);
                den1 = eval(app.DnominateurEditField_2.Value);
                num2 = eval(app.NumrateurEditField.Value);
                den2 = eval(app.DnominateurEditField.Value);
                num3 = eval(app.NumrateurEditField_3.Value);
                den3 = eval(app.DnominateurEditField_3.Value);

    % Vérification
                if isnan(P) || isnan(I) || isnan(Consigne) || isnan(tempsSimulation)
                    uialert(app.UIFigure, 'Les valeurs entrées ne sont pas valides', 'Erreur');
                    return;
                end

    % Mettre à jour Simulink
                assignin('base', 'P', P);
                assignin('base', 'I', I);
                assignin('base', 'Consigne', Consigne);
                set_param('Design2simulink', 'StopTime', num2str(tempsSimulation));

                try
                    set_param("Design2simulink/PID Controller", 'P', num2str(P), 'I', num2str(I));
                    set_param('Design2simulink/Step', 'After', num2str(Consigne));
                    %uialert(app.UIFigure, ['hello : ' [num2str(num1)]], 'Erreur');
                    set_param('Design2simulink/Procédé/Plaque/T1', 'Numerator', mat2str(num1), 'Denominator', mat2str(den1));
                    set_param('Design2simulink/Procédé/Plaque/T2', 'Numerator', mat2str(num2), 'Denominator', mat2str(den2));
                    set_param('Design2simulink/Procédé/Plaque/T3', 'Numerator', mat2str(num3), 'Denominator', mat2str(den3));

                catch ME

                    uialert(app.UIFigure, ['Erreur dans Simulink : ' ME.message], 'Erreur');
                    return;
                end

                % Lancer la simulation
                startSimulation(app, tempsSimulation);
                %app.Timer = timer('ExecutionMode', 'fixedRate', 'Period', 1, 'TimerFcn', @(~,~)updatePlot(app));
                %start(app.Timer);
               
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Réponse du système')
            xlabel(app.UIAxes, 'Temps (s)')
            ylabel(app.UIAxes, 'Température (°C)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @UIFigureButtonDown, true);
            app.ConsigneEditFieldLabel = uilabel(app.UIFigure);
            app.ConsigneEditFieldLabel.Text = 'Consigne';
            app.ConsigneEditField = uieditfield(app.UIFigure, 'numeric');
            app.ConsigneEditField.Value = 25;
            app.PEditFieldLabel = uilabel(app.UIFigure);
            app.PEditFieldLabel.Text = 'P';
            app.PEditField = uieditfield(app.UIFigure, 'numeric');
            app.PEditField.Value = 0.53;
            app.IEditFieldLabel = uilabel(app.UIFigure);
            app.IEditFieldLabel.Text = 'I';
            app.IEditField = uieditfield(app.UIFigure, 'numeric');
            app.IEditField.Value = 0.0049;
            app.ValideretlancerButton = uibutton(app.UIFigure, 'push');
            app.ValideretlancerButton.ButtonPushedFcn = createCallbackFcn(app, @ValideretlancerButtonPushed, true);
            app.ValideretlancerButton.Text = 'Valider et lancer';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'bab.jpeg');
            app.RgulateurLabel = uilabel(app.UIFigure);
            app.RgulateurLabel.Text = 'Régulateur';
            app.ThermistancesLabel = uilabel(app.UIFigure);
            app.ThermistancesLabel.Text = 'Thermistances :';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.Tab = uitab(app.TabGroup);
            app.Tab.Title = '1';
            app.NumrateurEditField_2Label = uilabel(app.Tab);
            app.NumrateurEditField_2Label.Text = 'Numérateur ';
            app.NumrateurEditField_2 = uieditfield(app.Tab, 'text');
            app.NumrateurEditField_2.Value = '[8.07]';
            app.DnominateurEditField_2Label = uilabel(app.Tab);
            app.DnominateurEditField_2Label.Text = 'Dénominateur ';
            app.DnominateurEditField_2 = uieditfield(app.Tab, 'text');
            app.DnominateurEditField_2.Value = '[90.09 1]';
            app.Tab_2 = uitab(app.TabGroup);
            app.Tab_2.Title = '2';
            app.NumrateurEditFieldLabel = uilabel(app.Tab_2);
            app.NumrateurEditFieldLabel.Text = 'Numérateur ';
            app.NumrateurEditField = uieditfield(app.Tab_2, 'text');
            app.NumrateurEditField.Value = '[0.789]';
            app.DnominateurEditFieldLabel = uilabel(app.Tab_2);
            app.DnominateurEditFieldLabel.Text = 'Dénominateur';
            app.DnominateurEditField = uieditfield(app.Tab_2, 'text');
            app.DnominateurEditField.Value = '[40.49 1]';
            app.Tab_3 = uitab(app.TabGroup);
            app.Tab_3.Title = '3';
            app.NumrateurEditField_3Label = uilabel(app.Tab_3);
            app.NumrateurEditField_3Label.Text = 'Numérateur ';
            app.NumrateurEditField_3 = uieditfield(app.Tab_3, 'text');
            app.NumrateurEditField_3.Value = '[0.921]';
            app.DnominateurEditField_3Label = uilabel(app.Tab_3);
            app.DnominateurEditField_3Label.Text = 'Dénominateur ';
            app.DnominateurEditField_3 = uieditfield(app.Tab_3, 'text');
            app.DnominateurEditField_3.Value = '[20.28 1]';
            app.TempssimEditFieldLabel = uilabel(app.UIFigure);
            app.TempssimEditFieldLabel.Text = 'Temps sim';
            app.TempssimEditField = uieditfield(app.UIFigure, 'numeric');
            app.TempssimEditField.Value = 2000;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = application

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end